classdef Ejercicio2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        QueanimalesButton    matlab.ui.control.Button
        AnimalesButtonGroup  matlab.ui.container.ButtonGroup
        GatoButton           matlab.ui.control.RadioButton
        PerroButton          matlab.ui.control.RadioButton
        HamsterButton        matlab.ui.control.RadioButton
        CaballoButton        matlab.ui.control.RadioButton
        ArdillaButton        matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: AnimalesButtonGroup
        function AnimalesButtonGroupSelectionChanged(app, event)
            %selectedButton = app.AnimalesButtonGroup.SelectedObject;
            
            if app.GatoButton.Value == true
                SelectedAnimal = 0;
            end
            if app.PerroButton.Value == true
                SelectedAnimal = 1;
            end
            if app.HamsterButton.Value == true
                SelectedAnimal = 2;
            end
            if app.CaballoButton.Value == true
                SelectedAnimal = 3;
            end
            if app.ArdillaButton.Value == true
                SelectedAnimal = 4;
            end
            
        end

        % Button pushed function: QueanimalesButton
        function QueanimalesButtonPushed(app, event)
            switch SelectedAnimal
                case 0
                    msgbox('gato')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnimalesButtonGroup = uibuttongroup(app.UIFigure);
            app.AnimalesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnimalesButtonGroupSelectionChanged, true);
            app.AnimalesButtonGroup.Title = 'Animales';
            app.ArdillaButton = uiradiobutton(app.AnimalesButtonGroup);
            app.ArdillaButton.Text = 'Ardilla';
            app.CaballoButton = uiradiobutton(app.AnimalesButtonGroup);
            app.CaballoButton.Text = 'Caballo';
            app.HamsterButton = uiradiobutton(app.AnimalesButtonGroup);
            app.HamsterButton.Text = 'Hamster';
            app.PerroButton = uiradiobutton(app.AnimalesButtonGroup);
            app.PerroButton.Text = 'Perro';
            app.GatoButton = uiradiobutton(app.AnimalesButtonGroup);
            app.GatoButton.Text = 'Gato';
            app.GatoButton.Value = true;
            app.QueanimalesButton = uibutton(app.UIFigure, 'push');
            app.QueanimalesButton.ButtonPushedFcn = createCallbackFcn(app, @QueanimalesButtonPushed, true);
            app.QueanimalesButton.Text = '¿Que animal es?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ejercicio2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end