classdef Ejercicio3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Frecuencia3                  matlab.ui.control.NumericEditField
        FrecuenciademuestreoLabel_3  matlab.ui.control.Label
        Tiempo3                      matlab.ui.control.NumericEditField
        TiempoEditFieldLabel_3       matlab.ui.control.Label
        Graficar3                    matlab.ui.control.Button
        Frecuencia2                  matlab.ui.control.NumericEditField
        FrecuenciademuestreoLabel_2  matlab.ui.control.Label
        Tiempo2                      matlab.ui.control.NumericEditField
        TiempoEditFieldLabel_2       matlab.ui.control.Label
        Frecuencia1                  matlab.ui.control.NumericEditField
        FrecuenciademuestreoLabel    matlab.ui.control.Label
        Tiempo1                      matlab.ui.control.NumericEditField
        TiempoEditFieldLabel         matlab.ui.control.Label
        Texto2                       matlab.ui.control.EditField
        Texto3                       matlab.ui.control.EditField
        Texto1                       matlab.ui.control.EditField
        Graficar2                    matlab.ui.control.Button
        Graficar1                    matlab.ui.control.Button
        KerneldeoperacinLabel        matlab.ui.control.Label
        Onda2Label                   matlab.ui.control.Label
        Onda1Label                   matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes1                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Graficar1
        function Graficar1ButtonPushed(app, event)
            fs = app.Frecuencia1.Value;
            time = app.Tiempo1.Value;
            t = 1/fs:1/fs:time;

            value = app.Texto1.Value;
            A = eval(value);

            plot(app.UIAxes1,t,A)
        end

        % Button pushed function: Graficar2
        function Graficar2ButtonPushed(app, event)
            fs = app.Frecuencia2.Value;
            time = app.Tiempo2.Value;
            t = 1/fs:1/fs:time;

            value = app.Texto2.Value;
            B = eval(value);

            plot(app.UIAxes2,t,B)
        end

        % Button pushed function: Graficar3
        function Graficar3ButtonPushed(app, event)
            fs = app.Frecuencia3.Value;
            time = app.Tiempo3.Value;
            t = 1/fs:1/fs:time;

            value = app.Texto1.Value;
            A = eval(value);

            value = app.Texto2.Value;
            B = eval(value);

            value = app.Texto3.Value;
            operation = eval(value);

            plot(app.UIAxes3,t,operation)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'A')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'B')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Operación')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Onda1Label = uilabel(app.UIFigure);
            app.Onda1Label.Text = 'Onda 1';
            app.Onda2Label = uilabel(app.UIFigure);
            app.Onda2Label.Text = 'Onda 2';
            app.KerneldeoperacinLabel = uilabel(app.UIFigure);
            app.KerneldeoperacinLabel.Text = {'Kernel de '; 'operación'};
            app.Graficar1 = uibutton(app.UIFigure, 'push');
            app.Graficar1.ButtonPushedFcn = createCallbackFcn(app, @Graficar1ButtonPushed, true);
            app.Graficar1.Text = 'Graficar';
            app.Graficar2 = uibutton(app.UIFigure, 'push');
            app.Graficar2.ButtonPushedFcn = createCallbackFcn(app, @Graficar2ButtonPushed, true);
            app.Graficar2.Text = 'Graficar';
            app.Texto1 = uieditfield(app.UIFigure, 'text');
            app.Texto3 = uieditfield(app.UIFigure, 'text');
            app.Texto2 = uieditfield(app.UIFigure, 'text');
            app.TiempoEditFieldLabel = uilabel(app.UIFigure);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.Tiempo1 = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciademuestreoLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreoLabel.Text = {'Frecuencia'; 'de muestreo'};
            app.Frecuencia1 = uieditfield(app.UIFigure, 'numeric');
            app.TiempoEditFieldLabel_2 = uilabel(app.UIFigure);
            app.TiempoEditFieldLabel_2.Text = 'Tiempo';
            app.Tiempo2 = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciademuestreoLabel_2 = uilabel(app.UIFigure);
            app.FrecuenciademuestreoLabel_2.Text = {'Frecuencia'; 'de muestreo'};
            app.Frecuencia2 = uieditfield(app.UIFigure, 'numeric');
            app.Graficar3 = uibutton(app.UIFigure, 'push');
            app.Graficar3.ButtonPushedFcn = createCallbackFcn(app, @Graficar3ButtonPushed, true);
            app.Graficar3.Text = 'Graficar';
            app.TiempoEditFieldLabel_3 = uilabel(app.UIFigure);
            app.TiempoEditFieldLabel_3.Text = 'Tiempo';
            app.Tiempo3 = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciademuestreoLabel_3 = uilabel(app.UIFigure);
            app.FrecuenciademuestreoLabel_3.Text = {'Frecuencia'; 'de muestreo'};
            app.Frecuencia3 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ejercicio3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end