classdef Examen_2_practico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GarcaOrtizLenin215671599Label  matlab.ui.control.Label
        Graficar_5                     matlab.ui.control.Button
        Graficar_4                     matlab.ui.control.Button
        Graficar_3                     matlab.ui.control.Button
        Seal2Label                     matlab.ui.control.Label
        Texto_2                        matlab.ui.control.EditField
        Graficar_2                     matlab.ui.control.Button
        Texto_1                        matlab.ui.control.EditField
        Graficar_1                     matlab.ui.control.Button
        Seal1Label                     matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_1                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Graficar_1
        function Graficar_1ButtonPushed(app, event)
            fs = 1000;
            time = 1;
            t = 1/fs:1/fs:time;

            value = app.Texto_1.Value;
            onda1 = eval(value);

            plot(app.UIAxes_1,t,onda1)
        end

        % Button pushed function: Graficar_2
        function Graficar_2ButtonPushed(app, event)
            fs = 1000;
            time = 1;
            t = 1/fs:1/fs:time;

            value = app.Texto_2.Value;
            onda2 = eval(value);

            plot(app.UIAxes_1,t,onda2)
        end

        % Button pushed function: Graficar_3
        function Graficar_3ButtonPushed(app, event)
            fs = 1000;
            time = 1;
            t = 1/fs:1/fs:time;

            value = app.Texto_1.Value;
            onda1 = eval(value);

            value = app.Texto_2.Value;
            onda2 = eval(value);

            suma = onda1 + onda2;
            plot(app.UIAxes_2,t,suma)
            xlim(app.UIAxes_2,[0 1])
        end

        % Button pushed function: Graficar_4
        function Graficar_4ButtonPushed(app, event)
            fs = 1000;
            time = 1;
            t = 1/fs:1/fs:time;
            f = fs*(0:((fs*t(end))/2))/(fs*t(end));

            value = app.Texto_1.Value;
            onda1 = eval(value);

            value = app.Texto_2.Value;
            onda2 = eval(value);

            suma = onda1 + onda2;
            fourier = abs(fft(suma));
            fourier = fourier(1:(fs*t(end))/2+1);
            fourier(2:end-1) = 2*fourier(2:end-1)

            plot(app.UIAxes_2, f, fourier)
            xlim(app.UIAxes_2, [0 100])
        end

        % Button pushed function: Graficar_5
        function Graficar_5ButtonPushed(app, event)
            fs = 1000;
            time = 1;
            t = 1/fs:1/fs:time;

            value = app.Texto_1.Value;
            onda1 = eval(value);

            value = app.Texto_2.Value;
            onda2 = eval(value);

            suma = onda1+onda2;
            promedio = mean(suma);
            plot(app.UIAxes_2, promedio, promedio, LineWidth=2)
            xlim(app.UIAxes_2, 'auto')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Señales componentes')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Señales procesadas')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Seal1Label = uilabel(app.UIFigure);
            app.Seal1Label.Text = 'Señal 1';
            app.Graficar_1 = uibutton(app.UIFigure, 'push');
            app.Graficar_1.ButtonPushedFcn = createCallbackFcn(app, @Graficar_1ButtonPushed, true);
            app.Graficar_1.Text = 'Agregar';
            app.Texto_1 = uieditfield(app.UIFigure, 'text');
            app.Graficar_2 = uibutton(app.UIFigure, 'push');
            app.Graficar_2.ButtonPushedFcn = createCallbackFcn(app, @Graficar_2ButtonPushed, true);
            app.Graficar_2.Text = 'Agregar';
            app.Texto_2 = uieditfield(app.UIFigure, 'text');
            app.Seal2Label = uilabel(app.UIFigure);
            app.Seal2Label.Text = 'Señal 2';
            app.Graficar_3 = uibutton(app.UIFigure, 'push');
            app.Graficar_3.ButtonPushedFcn = createCallbackFcn(app, @Graficar_3ButtonPushed, true);
            app.Graficar_3.Text = 'Suma de señales';
            app.Graficar_4 = uibutton(app.UIFigure, 'push');
            app.Graficar_4.ButtonPushedFcn = createCallbackFcn(app, @Graficar_4ButtonPushed, true);
            app.Graficar_4.Text = 'Espectro de fourier';
            app.Graficar_5 = uibutton(app.UIFigure, 'push');
            app.Graficar_5.ButtonPushedFcn = createCallbackFcn(app, @Graficar_5ButtonPushed, true);
            app.Graficar_5.Text = 'Promedio';
            app.GarcaOrtizLenin215671599Label = uilabel(app.UIFigure);
            app.GarcaOrtizLenin215671599Label.Text = 'García Ortiz Lenin - 215671599';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Examen_2_practico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end